classdef progetto_matlab_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgettoUIFigure              matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DatirealiTab                  matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        GraficiTab                    matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        Grafico1Tab                   matlab.ui.container.Tab
        Grafico_1                     matlab.ui.control.UIAxes
        Grafico2Tab                   matlab.ui.container.Tab
        Grafico_2                     matlab.ui.control.UIAxes
        Grafico3Tab                   matlab.ui.container.Tab
        Grafico_3                     matlab.ui.control.UIAxes
        Grafico4Tab                   matlab.ui.container.Tab
        Grafico_4                     matlab.ui.control.UIAxes
        Grafico5Tab                   matlab.ui.container.Tab
        Grafico_5                     matlab.ui.control.UIAxes
        Grafico3DTab                  matlab.ui.container.Tab
        Grafico_6                     matlab.ui.control.UIAxes
        TabellaRisultati              matlab.ui.control.Table
        FunzioniExtraLabel            matlab.ui.control.Label
        FunzioniExtra                 matlab.ui.control.DropDown
        StampaPDFButton               matlab.ui.control.Button
        SelezionaDatidaVisualizareLabel  matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        TuttiiRisultatideiTestTab     matlab.ui.container.Tab
        TabelaTuttiTest               matlab.ui.control.Table
        EsportainLaTeXButtonGroup     matlab.ui.container.ButtonGroup
        SiButton                      matlab.ui.control.RadioButton
        NoButton                      matlab.ui.control.RadioButton
        CaricaTabella                 matlab.ui.control.Button
        DatitestconsimulazioneTab     matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        GraficiTab_2                  matlab.ui.container.Tab
        TabGroup5                     matlab.ui.container.TabGroup
        Grafico1Tab_2                 matlab.ui.container.Tab
        Grafico_2_1                   matlab.ui.control.UIAxes
        Grafico2Tab_2                 matlab.ui.container.Tab
        Grafico_2_2                   matlab.ui.control.UIAxes
        Grafico3Tab_2                 matlab.ui.container.Tab
        Grafico_2_3                   matlab.ui.control.UIAxes
        Grafico4Tab_2                 matlab.ui.container.Tab
        Grafico_2_4                   matlab.ui.control.UIAxes
        Grafico5Tab_2                 matlab.ui.container.Tab
        Grafico_2_5                   matlab.ui.control.UIAxes
        Grafico3DTab_2                matlab.ui.container.Tab
        Grafico_2_6                   matlab.ui.control.UIAxes
        FunzioniExtraDropDownLabel    matlab.ui.control.Label
        FunzioniExtra_2               matlab.ui.control.DropDown
        TabellaRisultati_2            matlab.ui.control.Table
        StampaPDFButton_2             matlab.ui.control.Button
        SelezionaDatidaVisualizareListBoxLabel  matlab.ui.control.Label
        ListBox_2                     matlab.ui.control.ListBox
        TuttiiRisultatideiTestTab_2   matlab.ui.container.Tab
        TabellaTuttiTest_2            matlab.ui.control.Table
        CaricaTabella_2               matlab.ui.control.Button
        EsportainLaTeXButtonGroup_2   matlab.ui.container.ButtonGroup
        SiButton_2                    matlab.ui.control.RadioButton
        NoButton_2                    matlab.ui.control.RadioButton
        DatiacquisiticontelefonoTab   matlab.ui.container.Tab
        TabGroup6                     matlab.ui.container.TabGroup
        GraficiTab_3                  matlab.ui.container.Tab
        TabGroup7                     matlab.ui.container.TabGroup
        Grafico1Tab_3                 matlab.ui.container.Tab
        Grafico_3_1                   matlab.ui.control.UIAxes
        Grafico2Tab_3                 matlab.ui.container.Tab
        Grafico_3_2                   matlab.ui.control.UIAxes
        Grafico3Tab_3                 matlab.ui.container.Tab
        Grafico_3_3                   matlab.ui.control.UIAxes
        Grafico4Tab_3                 matlab.ui.container.Tab
        Grafico_3_4                   matlab.ui.control.UIAxes
        Grafico5Tab_3                 matlab.ui.container.Tab
        Grafico_3_5                   matlab.ui.control.UIAxes
        Grafico3DTab_3                matlab.ui.container.Tab
        Grafico_3_6                   matlab.ui.control.UIAxes
        SelezionaDatidaVisualizareListBoxLabel_2  matlab.ui.control.Label
        ListBox_3                     matlab.ui.control.ListBox
        TabellaRisultati_3            matlab.ui.control.Table
        FunzioniExtraDropDownLabel_2  matlab.ui.control.Label
        FunzioniExtra_3               matlab.ui.control.DropDown
        StampaPDFButton_3             matlab.ui.control.Button
        TuttiiRisultatideiTestTab_3   matlab.ui.container.Tab
        TabellaTuttiTest_3            matlab.ui.control.Table
        CaricaTabella_3               matlab.ui.control.Button
        EsportainLaTeXButtonGroup_3   matlab.ui.container.ButtonGroup
        SiButton_3                    matlab.ui.control.RadioButton
        NoButton_3                    matlab.ui.control.RadioButton
    end


    % Conversione del progetto da GUIDE ad appdesigner
    % Dritan Progri 17/10/2017
    % **************************************************************************
    % Promemoria: cercare di ottimizare il codice con meno variabili rindondanti
    % uso di preallocazione e riutilizo di funzioni
    % **************************************************************************
    % 26/10/2017 versione 1.16:
    % modificata l'interfaccia grafica con le nuove ricchieste 
    % 29/10/2017 versione 1_27:
    % aggiunto nomi colone/righe alla tabella LaTeX
    % aggiunto grafico 3D dei angoli psi,theta,phi
    % versione 1_30:
    % scambiati i posti di S e X per la visualizazione
    % aggiunto casella a scelta Funzioni extra
    % funzione CreaFigure 
    % 30/10/2017 versione 1_36:
    % cambiato nome in opzioni di funzioni extra
    % aggiunto pulsante Stampa PDF
    % divisi i grafici da mostrare in figura in 2 figure diverse...
    % (aggiunto anche il grafico 3D)
    % versione 1_37:
    % aggiunta chiusura figure con pulsante Stampa PDF * varie ottimizazioni
    % versione 1_38:
    % aggiunta funzione stampaPdf per stampare le figure in pdf
    % 10/11/2017 versione 1_44:
    % transformata la funzione creagrafico in tanti grafici diversi 
    % 16/11/17 versione 1_45:
    % correto errore calcolo variabile X
    % sistemata risuluzione figure da salvare in pdf
    % uso di git per il controllo di versione
    
    properties (Access = private)
        N_t = [];
        D_t = [];
        X_t = [];
        L_t  = [];      % Description
        S_t = [];       % Description
        Lu_t = [];      % Description
        AnLu_t = [];    % Description
        Psi = [];       % angolo
        Theta = [];     % angolo
        Phi = [];       % angolo
        x_t = []; % Description
        v_t = []; % Description
        Fs = 15; % Description
        FigArray = [1 2]; % Description
        dataSet = {'Dataset 1','Dataset 2','Dataset 3','Dataset 4','Dataset 5','Dataset 6','Dataset 7','Dataset 8','Dataset 9','Dataset 10'}; % Description
        Label = {'Yaw $\psi_k$ [rad]','Pitch $\theta_k$ [rad]','Roll $\phi_k$ [rad]','Val. Analitico'}; % Description
        titolo = []; % Description
        indice = []; % Description
        nome_1 = []; % Description
        nome_2 = []; % Description
        nomiFile = {'Cammino.mat','Corsa.mat','Salti.mat'}; % Description
        Lxv_t = []; % Description

    end
  
    methods (Access = private)
    
        
        function [N , R , psi , theta , phi, dati] = caricaDati(app,file)
            
            load(file);
            dati = RPY_Data*pi/180;
            N = size(dati,2);       % Numero di campioni acquisiti
            R=2500;                 %prime 2500 ierazioni da non considerare
            psi= dati(1,R:N);
            theta= dati(2,R:N);
            phi= dati(3,R:N);
            
        end
        
        
        function [Lxv,x,v,N,D,X,l,s,LurchIndex,AnalyticLurchIndex] = calcola(app,N,R,Psi,Theta,Phi)

            load('lib.mat');
            psi=unwrap(Psi);
            theta=unwrap(Theta);
            phi=unwrap(Phi);
            app.Psi = psi;
            app.Theta = theta;
            app.Phi = phi;

            t=0.1;                  %Sempre uguale per tutti i test
            T=t*(N-R);              %tempo totale

            dpsi=diff(psi)/t;
            dtheta=diff(theta)/t;
            dphi=diff(phi)/t;

            ddpsi=diff(dpsi)/t;
            ddtheta=diff(dtheta)/t;
            ddphi=diff(dphi)/t;

            dddpsi=diff(ddpsi)/t;
            dddtheta=diff(ddtheta)/t;
            dddphi=diff(ddphi)/t;
            x=zeros(1,N-R-3);
            
            for i=1:N-R-3           %valori attesi di v dalla formula analitica
                 x(i)=jerk_analitico(psi(i),theta(i),phi(i),dpsi(i),dtheta(i),dphi(i),ddpsi(i),ddtheta(i),ddphi(i),dddpsi(i),dddtheta(i),dddphi(i));
   
            end
            X = sum(x) * t;             

            i = length(dpsi);
            wx = zeros(1,i); wy = zeros(1,i); wz = zeros(1,i);   
            wx = dtheta.*sin(psi(1:i)) + dphi.*cos(psi(1:i)).*cos(theta(1:i));
            wy = dphi.*cos(theta(1:i)).*sin(psi(1:i)) - dtheta.*cos(psi(1:i));
            wz = dpsi + dphi.*sin(theta(1:i));
            W=[wx;wy;wz];           %velocità angolare in funzione degli angoli

            dwx= diff(wx)/t;
            dwy= diff(wy)/t;
            dwz= diff(wz)/t;
            dW=[dwx;dwy;dwz];

            ddwx= diff(dwx)/t; 
            ddwy= diff(dwy)/t;     
            ddwz= diff(dwz)/t;
            ddW=[ddwx;ddwy;ddwz];

        
            i=length(ddwx);
            Vx=zeros(1,i);
            Vy=zeros(1,i); 
            Vz=zeros(1,i); 
            
            i=1;
            while (i<N-2-R)           %perchè quando derivo perdo un elemento 
                a=[wx(i);wy(i);wz(i)];
                b=[dwx(i);dwy(i);dwz(i)];
                k=(cross(a,b))/2;
                Vx(i)=ddwx(i)+k(1,1);
                Vy(i)=ddwy(i)+k(2,1);
                Vz(i)=ddwz(i)+k(3,1);
                v(i)=norm([Vx(i);Vy(i);Vz(i)]);
                i=i+1;
            end 
            
            %Valorizzo l'integrando V            
            V=sum(v(1:(i-1)));
            l=V*t;
            s=0;
            i=1;
            
            while (i<N-2-R)
                m(i)=norm([wx(i);wy(i);wz(i)]);
                s=s+m(i);
                i=i+1;
            end
            
            Lxv = length(x);
            D=s*t;
            LurchIndex = T^2*l/D;
            AnalyticLurchIndex=T^2*X/D;
            
            %carico i valori finali
            app.N_t = [app.N_t N];
            app.D_t = [app.D_t D];
            app.X_t = [app.X_t X];
            app.L_t = [app.L_t l];
            app.S_t = [app.S_t s];
            app.Lu_t = [app.Lu_t LurchIndex];
            app.AnLu_t = [app.AnLu_t AnalyticLurchIndex];
            app.x_t = [app.x_t x];
            app.v_t = [app.v_t v];
            app.Lxv_t = [app.Lxv_t Lxv];
            
        end


        function tuttiTest(app,file,funzione)
            n = length(file);
            for k = 1:n
                [N , R , psi , theta , phi, ~] = funzione(app,file{k});
                [~ ,~ ,~,~,~,~] = calcola(app,N , R , psi , theta , phi);
            end    
        end
        
        function salvaFile(app,latex)
           
           [FileName, PathName] = uiputfile('.tex','Esporta in LaTex','tabella.tex');
           percorsoCompleto = PathName;
           percorsoCompleto = [percorsoCompleto FileName];
           if FileName ~= 0
                fid=fopen(percorsoCompleto,'wt+');
                [nrows,~] = size(latex);
                for row = 1:nrows
                    fprintf(fid,'%s\n',latex{row,:});
                end
                fclose(fid);
           end
        end
        
        
        function latex = creaLaTeX(app,dati,nomiRighe,nomiColone,titolo)
            % *********************************************************************************************
            % il codice è stato preso da latextable e adatato al mio caso, perche l'originale dava problemi 
            % https://it.mathworks.com/matlabcentral/fileexchange/44274-latextable
            % *********************************************************************************************
            exsistRowName = ~isempty(nomiRighe);                        % mi ritorna 1 se ci sono nomi righe
            exsistColName = ~isempty(nomiColone);
            dimTabella = [exsistRowName + size(dati,1),exsistColName + size(dati,2)];
            tabellaInCellArray = cell(dimTabella);                      %creo un cellAray della dimnsione della tabella
            tabellaInCellArray(2:end,1) = nomiRighe;
            tabellaInCellArray(1,1 + exsistColName:end) = nomiColone;   %comincio a scrivere dalla seconda colona
            tabellaInCellArray(1 + exsistRowName:end,1 + exsistColName:end) = num2cell(dati); % aggiungo i dati
            hLine = '\hline';
            header = ['\begin{tabular}','{|l|',repmat(['c','|'],1,size(tabellaInCellArray,2) -1),'}'];
            latex = {['\begin{table}','[h!]'];'\centering';header};

            for i=1:size(tabellaInCellArray,1)
                latex(end+1) = {hLine};
                rowStr = '';
                for j=1:size(tabellaInCellArray,2)
                    dataValue = tabellaInCellArray{i,j};
                    if iscell(dataValue)
                        dataValue = dataValue{:};
                    elseif isnan(dataValue)
                        dataValue = input.dataNanString;
                    elseif isnumeric(dataValue)
                         dataValue = num2str(dataValue,'%.1f');
                    end
                    if j==1
                        rowStr = dataValue;
                    else
                        rowStr = [rowStr,' & ',dataValue];
                    end
                end
                latex(end+1) = {[rowStr,' \\']};
            end

            tableFooter = {'\end{tabular}';['\caption{',titolo,'}']; ...
            ['\label{tab:table1}'];'\end{table}'};
            latex = [latex;{hLine};tableFooter];
            latexHeader = {'\documentclass[a4paper,10pt]{article}','\begin{document}'};
            latexFooter = {'\end{document}'};
            latex = [latexHeader';latex;latexFooter];


        end
        
        function grafico3D(app,contenitore)
            
            plot3(contenitore,app.Psi,app.Theta,app.Phi);
            xlabel(contenitore,app.Label{1},'Interpreter','LaTeX','FontSize',app.Fs);
            ylabel(contenitore,app.Label{2},'Interpreter','LaTeX','FontSize',app.Fs);
            zlabel(contenitore,app.Label{3},'Interpreter','LaTeX','FontSize',app.Fs);
            xlim(contenitore,[min(app.Psi) max(app.Psi)]);
            ylim(contenitore,[min(app.Theta) max(app.Theta)]);
            zlim(contenitore,[min(app.Phi) max(app.Phi)]);
            contenitore.ZGrid = 'on';
        end
        
        function creaFigure(app,dati,R)
            
            app.nome_1 = app.titolo;
            app.nome_1 = [app.nome_1 'Fig1'];
            app.nome_2 = app.titolo;
            app.nome_2 = [app.nome_2 'Fig2'];
            close(findobj('type','figure','name',app.nome_1));
            close(findobj('type','figure','name',app.nome_2));
            
            f1 = figure;
            f1.PaperType = 'a4';
            f1.PaperUnits = 'centimeters';
            f1.PaperPosition = [-1.5 -1.5 24.5 32.5];
            f1.Name = app.nome_1;
            movegui(f1,'northwest');
            
            subplot(2,1,1);  
            
            plot(1:app.Lxv_t,app.x_t,'b.',1:app.Lxv_t,app.v_t,'or');
            axis tight;
            title(app.titolo,'Interpreter','LaTeX','FontSize',app.Fs);
            xlabel('Time-index $$k$$','Interpreter','LaTeX','FontSize',app.Fs);
            ylabel('Value of $$||\ell_k||$$','Interpreter','LaTeX','FontSize',app.Fs);
            legend('Analytic formula','Numerical approximation.','Location','northeast');        
            grid 'on'
            
       
            lim=max([app.v_t app.x_t]); 
            subplot(2,1,2); 
            plot(app.x_t,app.v_t,'k.',[0 lim],[0 lim],'b'); %Se la risultante ? la bisettrice del 1 quadrrante allora i valori della x sono uguali ai valori della v
            axis tight;
            xlabel ('Analytic formula','Interpreter','LaTeX','FontSize',app.Fs);
            ylabel ('Numerical approximation.','Interpreter','LaTeX','FontSize',app.Fs);
            grid 'on'
            
            f2 = figure;
            f2.PaperType = 'a4';
            f2.PaperUnits = 'centimeters';
            f2.PaperPosition = [-1.5 -1.5 24.5 32.5];
            f2.Name = app.nome_2;
            movegui(f2,'northeast');
            
            subplot(2,1,1);
            
            for r = 1:3
               dati(r,:) = unwrap(dati(r,:));    
               plot(1:app.N_t,dati(r,1:app.N_t),'LineWidth',2); 
               if r >= 2
                   continue
               end
               hold 'on'
            end

             
            xlim([R app.N_t])     
            title(app.titolo,'Interpreter','LaTeX','FontSize',app.Fs);
            ylabel('$$\psi_k$$, $$\theta_k$$, $$\phi_k$$ [rad]','Interpreter','LaTeX','FontSize',app.Fs);
            xlabel('Time-index $$k$$','Interpreter','LaTeX','FontSize',app.Fs)
            grid 'on'
            
            
            subplot(2,1,2);
            
            plot3(app.Psi,app.Theta,app.Phi);
            xlabel(app.Label{1},'Interpreter','LaTeX','FontSize',app.Fs);
            ylabel(app.Label{2},'Interpreter','LaTeX','FontSize',app.Fs);
            zlabel(app.Label{3},'Interpreter','LaTeX','FontSize',app.Fs);
            xlim([min(app.Psi) max(app.Psi)]);
            ylim([min(app.Theta) max(app.Theta)]);
            zlim([min(app.Phi) max(app.Phi)]);
            grid 'on'
            
        end
        
        function stampaPdf(app)
            
            print(1,app.nome_1,'-dpdf');
            print(2,app.nome_2,'-dpdf');
        end
        
        function disegnaGrafico_1(app,Lxv,x,v,contenitore)
         
            plot(contenitore,1:Lxv,x,'b.',1:Lxv,v,'or');
            title(contenitore,app.titolo,'FontName','serif','FontWeight','normal','FontSize',11);
            xlabel(contenitore,'Indice temporale discreto $k$','Interpreter','LaTeX','FontSize',app.Fs)
            ylabel(contenitore,'Valore del lurch all''indice $k$','Interpreter','LaTeX','FontSize',app.Fs)
            legend(contenitore,'Valori ottenuti in maniera analitica','Valori ottenuti con approssimazioni numeriche')        
        
        end
        
        function disegnaGrafico_2(app,x,v,contenitore)
            
            lim=max([v x]);
            plot(contenitore,x,v,'k.',[0 lim],[0 lim],'b');
            title(contenitore,'Rapporto tra dati tratti in maniera analitica e in maniera numerica','FontName','serif','FontWeight','normal','FontSize',app.Fs)
            xlabel (contenitore,'Valori ottenuti con formula analitica','FontName','serif','FontSize',app.Fs)
            ylabel (contenitore,'Valori ottenuti con approssimazioni numeriche','FontName','serif','FontSize',app.Fs)
         
        end
        
        function disegnaGrafico_3(app,dati,N,R,contenitore)
            
            dati(1,:) = unwrap(dati(1,:));    
            plot(contenitore,1:N,dati(1,1:N),'LineWidth',2);
            title(contenitore,app.titolo,'FontName','serif','FontWeight','normal','FontSize',11)
            ylabel(contenitore,app.Label{1},'Interpreter','LaTeX','FontSize',app.Fs)
            xlim(contenitore,[R N])           
            xlabel(contenitore,'Sample $k$','Interpreter','LaTeX','FontSize',app.Fs)
        
        end
        
        function disegnaGrafico_4(app,dati,N,R,contenitore)
            
            dati(2,:) = unwrap(dati(2,:));    
            plot(contenitore,1:N,dati(2,1:N),'LineWidth',2);
            title(contenitore,app.titolo,'FontName','serif','FontWeight','normal','FontSize',11)
            ylabel(contenitore,app.Label{2},'Interpreter','LaTeX','FontSize',app.Fs)
            xlim(contenitore,[R N])           
            xlabel(contenitore,'Sample $k$','Interpreter','LaTeX','FontSize',app.Fs)
        
        end
        
        function disegnaGrafico_5(app,dati,N,R,contenitore)
            
            dati(3,:) = unwrap(dati(3,:));    
            plot(contenitore,1:N,dati(3,1:N),'LineWidth',2);
            title(contenitore,app.titolo,'FontName','serif','FontWeight','normal','FontSize',11)
            ylabel(contenitore,app.Label{3},'Interpreter','LaTeX','FontSize',app.Fs)
            xlim(contenitore,[R N])           
            xlabel(contenitore,'Sample $k$','Interpreter','LaTeX','FontSize',app.Fs)
       
        end
        
        function  indice = getIndex(app,lista)
            indice = find(ismember(lista.ItemsData,lista.Value));
        end
        
        function [N , R , psi , theta , phi,dati] = funzioneScattosa(app)
        
            t=0:0.1:4*pi;
            psi=sign(sin(t));
            theta=sign(sin(t+pi/2));
            phi=sign(sin(t+pi/4));
            dati=[psi;theta;phi];
            N=length(dati);
            R=0;
 
        end
        
        function [N , R , psi , theta , phi,dati] = funzioneSinusoidale(app)
           
            t=0:0.1:4*pi;
            psi=sin(t);
            theta=sin(t+pi/2);
            phi=sin(t+pi/4);
            dati=[psi;theta;phi];
            N=length(dati);
            R=0;

        end
        
        function [N , R , psi , theta , phi,dati] = funzioneConRumore(app)

            t=0:0.1:4*pi;
            psi=sin(t)+0.1*sin(10*t);
            theta=sin(t+pi/2)+0.1*sin(10*t);
            phi=sin(t+pi/4)+0.1*sin(10*t);
            dati=[psi;theta;phi];
            N=length(dati);
            R=0;

        end
        
        function [N , R , psi , theta , phi, dati] = caricaDati_3(app,file)
            load(file)
            dati = dati*pi/180;
            %dati = escludiErrore(app,dati);
            N = size(dati,2)-1;         %escludo l'ultimo campione
            psi = dati(1,1:N);
            theta = dati(2,1:N);
            phi = dati(3,1:N);
            R = 0;
        end
        
        function [N , R , psi , theta , phi,dati] = caricaDati_2(app,index)
            
            switch index
                case 1
                   [N , R , psi , theta , phi,dati] = funzioneScattosa(app);
                case 2
                   [N , R , psi , theta , phi,dati] = funzioneSinusoidale(app);
                case 3
                   [N , R , psi , theta , phi,dati] = funzioneConRumore(app);
            end
        end
        
        function dati_t = escludiErrore(app,dati)
             
            for c = 1:length(dati)
                if dati(1,c) >= dati(2,c) && dati(1,c) >= dati(3,c)
                    for n = 1:3
                       dati_t(n,c) = dati(n,c);
                    end
                else 
                      break
                end
            end
        
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ListBox.Items = app.dataSet;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            app.Psi = [];
            app.Theta = [];
            app.Phi = [];
            app.N_t = [];
            app.v_t = [];
            app.x_t = [];
            app.titolo = [];
            app.Lxv_t = [];
            app.titolo = app.dataSet{getIndex(app,app.ListBox)};
            app.FunzioniExtra.Enable = 'on';
            file = app.ListBox.Value;
            [N , R , psi , theta , phi,dati] = caricaDati(app,file);
            [Lxv,x,v,N, D, X,l,s,LurchIndex,AnalyticLurchIndex] = calcola(app,N , R , psi , theta , phi);
            disegnaGrafico_1(app,Lxv,x,v,app.Grafico_1);
            disegnaGrafico_2(app,x,v,app.Grafico_2);
            disegnaGrafico_3(app,dati,N,R,app.Grafico_3);
            disegnaGrafico_4(app,dati,N,R,app.Grafico_4);            
            disegnaGrafico_5(app,dati,N,R,app.Grafico_5);
            grafico3D(app,app.Grafico_6);
            pause(1);                            %serve la pausa se no, non je la fa... :D
            app.TabellaRisultati.Data = num2cell([N D s l X LurchIndex AnalyticLurchIndex]);
            
        end

        % Button pushed function: CaricaTabella
        function CaricaTabellaButtonPushed(app, event)
               app.N_t = [];   %
               app.D_t = [];   %
               app.X_t = [];   %
               app.L_t = [];   %   azero i dati vecchi
               app.S_t = [];   %
               app.Lu_t = [];  %
               app.AnLu_t = [];%
               file = app.ListBox.ItemsData;
               titolo = app.DatirealiTab.Title;
               tuttiTest(app,file,@caricaDati);
               dati = [app.N_t' app.D_t' app.S_t' app.L_t' app.X_t' app.Lu_t' app.AnLu_t'];
               nomiRighe = app.ListBox.Items;
               dataset = app.dataSet(1:length(app.ListBox.Items));
               app.TabelaTuttiTest.RowName = nomiRighe;
               nomiColone = app.TabelaTuttiTest.ColumnName;
               app.TabelaTuttiTest.Data = dati;
               if app.SiButton.Value == 1
                    pause(1);
                    latex = creaLaTeX(app,dati,dataset,nomiColone,titolo);
                    salvaFile(app,latex);
               end
        end

        % Value changed function: FunzioniExtra
        function FunzioniExtraValueChanged(app, event)
            value = app.FunzioniExtra.Value; 
            if strcmp(value,app.FunzioniExtra.Items{2})
                file = app.ListBox.Value;
                [~, R, ~ , ~ , ~, dati] = caricaDati(app,file);
                creaFigure(app,dati,R);
            end
            app.FunzioniExtra.Value = app.FunzioniExtra.Items{1};
            app.FunzioniExtra.Enable = 'off';
            app.StampaPDFButton.Enable = 'on';
        end

        % Button pushed function: StampaPDFButton
        function StampaPDFButtonPushed(app, event)
            app.StampaPDFButton.Enable = 'off';
            stampaPdf(app);
            close(app.FigArray); 
            msgbox('PDF salvati nella directory corrente','Stampa PDF','warn');
        end

        % Button pushed function: StampaPDFButton_2
        function StampaPDFButtonPushed_2(app, event)
            app.StampaPDFButton_2.Enable = 'off';
            stampaPdf(app);
            close(app.FigArray); 
            msgbox('PDF salvati nella directory corrente','Stampa PDF','warn');
        end

        % Value changed function: FunzioniExtra_2
        function FunzioniExtraValueChanged_2(app, event)
            value = app.FunzioniExtra_2.Value; 
            if strcmp(value,app.FunzioniExtra_2.Items{2})
                index = getIndex(app,app.ListBox_2);
                [N , R , psi , theta , phi,dati] = caricaDati_2(app,index);
                creaFigure(app,dati,R);
            end
            
            app.FunzioniExtra_2.Value = app.FunzioniExtra.Items{1};
            app.FunzioniExtra_2.Enable = 'off';
            app.StampaPDFButton_2.Enable = 'on';
            
        end

        % Value changed function: ListBox_2
        function ListBoxValueChanged_2(app, event)
            app.Psi = [];
            app.Theta = [];
            app.Phi = [];
            app.N_t = [];
            app.v_t = [];
            app.x_t = [];
            app.titolo = [];
            app.Lxv_t = [];
            index = getIndex(app,app.ListBox_2);
            app.titolo = app.dataSet{index};
            app.FunzioniExtra_2.Enable = 'on';
            [N , R , psi , theta , phi,dati] = caricaDati_2(app,index);                  
            [Lxv,x,v,N, D, X,l,s,LurchIndex,AnalyticLurchIndex] = calcola(app,N , R , psi , theta , phi);
            disegnaGrafico_1(app,Lxv,x,v,app.Grafico_2_1);
            disegnaGrafico_2(app,x,v,app.Grafico_2_2);
            disegnaGrafico_3(app,dati,N,R,app.Grafico_2_3);
            disegnaGrafico_4(app,dati,N,R,app.Grafico_2_4);
            [N , R , psi , theta , phi,dati] = caricaDati_2(app,index);
            disegnaGrafico_5(app,dati,N,R,app.Grafico_2_5);
            grafico3D(app,app.Grafico_2_6);
            pause(1);                            %serve la pausa se no, non je la fa... :D
            app.TabellaRisultati_2.Data = num2cell([N D s l X LurchIndex AnalyticLurchIndex]);
            
            
        end

        % Button pushed function: CaricaTabella_2
        function CaricaTabellaButtonPushed_2(app, event)
               app.N_t = [];   %
               app.D_t = [];   %
               app.X_t = [];   %
               app.L_t = [];   %   azero i dati vecchi
               app.S_t = [];   %
               app.Lu_t = [];  %
               app.AnLu_t = [];%
               titolo = app.DatitestconsimulazioneTab.Title;
               %tuttiTest(app,file);
               [N , R , psi , theta , phi,~] = funzioneScattosa(app);
               [~ ,~ ,~,~,~,~] = calcola(app,N,R,psi,theta,phi);
               [N , R , psi , theta , phi,~] = funzioneSinusoidale(app);
               [~ ,~ ,~,~,~,~] = calcola(app,N,R,psi,theta,phi);
               [N , R , psi , theta , phi,~] = funzioneConRumore(app);
               [~ ,~ ,~,~,~,~] = calcola(app,N,R,psi,theta,phi)
               dati = [app.N_t' app.D_t' app.S_t' app.L_t' app.X_t' app.Lu_t' app.AnLu_t'];
               nomiRighe = app.ListBox_2.Items;
               dataset = app.dataSet(1:length(app.ListBox_2.Items));
               app.TabellaTuttiTest_2.RowName = nomiRighe;
               nomiColone = app.TabellaTuttiTest_2.ColumnName;
               app.TabellaTuttiTest_2.Data = dati;
               if app.SiButton_2.Value == 1
                    pause(1);
                    latex = creaLaTeX(app,dati,dataset,nomiColone,titolo);
                    salvaFile(app,latex);
               end
        end

        % Value changed function: ListBox_3
        function ListBoxValueChanged_3(app, event)
            
            app.Psi = [];
            app.Theta = [];
            app.Phi = [];
            app.N_t = [];
            app.v_t = [];
            app.x_t = [];
            app.Lxv_t = [];
            app.titolo = [];
            app.titolo = app.dataSet{getIndex(app,app.ListBox_3)};
            app.FunzioniExtra_3.Enable = 'on';
            file = app.ListBox_3.Value;
            [N , R , psi , theta , phi,dati] = caricaDati_3(app,file);
            [Lxv,x,v,N, D, X,l,s,LurchIndex,AnalyticLurchIndex] = calcola(app,N , R , psi , theta , phi);
            disegnaGrafico_1(app,Lxv,x,v,app.Grafico_3_1);
            disegnaGrafico_2(app,x,v,app.Grafico_3_2);
            disegnaGrafico_3(app,dati,N,R,app.Grafico_3_3);
            disegnaGrafico_4(app,dati,N,R,app.Grafico_3_4);            
            disegnaGrafico_5(app,dati,N,R,app.Grafico_3_5);
            grafico3D(app,app.Grafico_3_6);
            pause(1);                            %serve la pausa se no, non je la fa... :D
            app.TabellaRisultati_3.Data = num2cell([N D s l X LurchIndex AnalyticLurchIndex]);
            
        end

        % Value changed function: FunzioniExtra_3
        function FunzioniExtraValueChanged_3(app, event)
            value = app.FunzioniExtra_3.Value;
            if strcmp(value,app.FunzioniExtra_3.Items{2})
                file = app.ListBox_3.Value;
                [~, R, ~ , ~ , ~, dati] = caricaDati_3(app,file);
                creaFigure(app,dati,R);
            end
            app.FunzioniExtra_3.Value = app.FunzioniExtra.Items{1};
            app.FunzioniExtra_3.Enable = 'off';
            app.StampaPDFButton_3.Enable = 'on';

        end

        % Button pushed function: StampaPDFButton_3
        function StampaPDFButtonPushed_3(app, event)
            app.StampaPDFButton_3.Enable = 'off';
            stampaPdf(app);
            close(app.FigArray); 
            msgbox('PDF salvati nella directory corrente','Stampa PDF','warn');
        
        end

        % Button pushed function: CaricaTabella_3
        function CaricaTabellaButtonPushed_3(app, event)
               app.N_t = [];   %
               app.D_t = [];   %
               app.X_t = [];   %
               app.L_t = [];   %   azzero i dati vecchi
               app.S_t = [];   %
               app.Lu_t = [];  %
               app.AnLu_t = [];%
               %file = app.ListBox_3.ItemsData;
               titolo = app.DatiacquisiticontelefonoTab.Title;
               tuttiTest(app,app.nomiFile,@caricaDati_3);
               dati = [app.N_t' app.D_t' app.S_t' app.L_t' app.X_t' app.Lu_t' app.AnLu_t'];
               nomiRighe = app.ListBox_3.Items;
               dataset = app.dataSet(1:length(app.ListBox_3.Items));
               app.TabellaTuttiTest_3.RowName = nomiRighe;
               nomiColone = app.TabellaTuttiTest_3.ColumnName;
               app.TabellaTuttiTest_3.Data = dati;
               if app.SiButton_3.Value == 1
                    pause(1);
                    latex = creaLaTeX(app,dati,dataset,nomiColone,titolo);
                    salvaFile(app,latex);
               end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgettoUIFigure = uifigure;
            app.ProgettoUIFigure.Name = 'Progetto';
            app.TabGroup = uitabgroup(app.ProgettoUIFigure);
            app.DatirealiTab = uitab(app.TabGroup);
            app.DatirealiTab.Title = 'Dati reali';
            app.TabGroup2 = uitabgroup(app.DatirealiTab);
            app.GraficiTab = uitab(app.TabGroup2);
            app.GraficiTab.Title = 'Grafici';
            app.TabGroup3 = uitabgroup(app.GraficiTab);
            app.Grafico1Tab = uitab(app.TabGroup3);
            app.Grafico1Tab.Title = 'Grafico 1';
            app.Grafico_1 = uiaxes(app.Grafico1Tab);
            xlabel(app.Grafico_1, 'Asse X')
            ylabel(app.Grafico_1, 'Asse Y')
            app.Grafico2Tab = uitab(app.TabGroup3);
            app.Grafico2Tab.Title = 'Grafico 2';
            app.Grafico_2 = uiaxes(app.Grafico2Tab);
            xlabel(app.Grafico_2, 'Asse X')
            ylabel(app.Grafico_2, 'Asse Y')
            app.Grafico3Tab = uitab(app.TabGroup3);
            app.Grafico3Tab.Title = 'Grafico 3';
            app.Grafico_3 = uiaxes(app.Grafico3Tab);
            xlabel(app.Grafico_3, 'Asse X')
            ylabel(app.Grafico_3, 'Asse Y')
            app.Grafico4Tab = uitab(app.TabGroup3);
            app.Grafico4Tab.Title = 'Grafico 4';
            app.Grafico_4 = uiaxes(app.Grafico4Tab);
            xlabel(app.Grafico_4, 'Asse X')
            ylabel(app.Grafico_4, 'Asse Y')
            app.Grafico5Tab = uitab(app.TabGroup3);
            app.Grafico5Tab.Title = 'Grafico 5';
            app.Grafico_5 = uiaxes(app.Grafico5Tab);
            xlabel(app.Grafico_5, 'Asse X')
            ylabel(app.Grafico_5, 'Asse Y')
            app.Grafico3DTab = uitab(app.TabGroup3);
            app.Grafico3DTab.Title = 'Grafico 3D';
            app.Grafico_6 = uiaxes(app.Grafico3DTab);
            xlabel(app.Grafico_6, 'Asse X')
            ylabel(app.Grafico_6, 'Asse Y')
            app.TabellaRisultati = uitable(app.GraficiTab);
            app.TabellaRisultati.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabellaRisultati.RowName = {};
            app.FunzioniExtraLabel = uilabel(app.GraficiTab);
            app.FunzioniExtraLabel.Text = 'Funzioni Extra';
            app.FunzioniExtra = uidropdown(app.GraficiTab);
            app.FunzioniExtra.Items = {'Nessuna', 'Mostra Figure'};
            app.FunzioniExtra.ValueChangedFcn = createCallbackFcn(app, @FunzioniExtraValueChanged, true);
            app.FunzioniExtra.Enable = 'off';
            app.FunzioniExtra.Value = 'Nessuna';
            app.StampaPDFButton = uibutton(app.GraficiTab, 'push');
            app.StampaPDFButton.ButtonPushedFcn = createCallbackFcn(app, @StampaPDFButtonPushed, true);
            app.StampaPDFButton.Enable = 'off';
            app.StampaPDFButton.Text = 'Stampa PDF';
            app.SelezionaDatidaVisualizareLabel = uilabel(app.GraficiTab);
            app.SelezionaDatidaVisualizareLabel.Text = 'Seleziona Dati da Visualizare';
            app.ListBox = uilistbox(app.GraficiTab);
            app.ListBox.Items = {'Non Scattoso v1 01dic15', 'Non Scattoso v2 01dic15', 'Scattoso v1 01dic15', 'Scattoso v2 01dic15', 'Test5 10dic15cle', 'Test6 10dic15', 'Test7 10dic15', 'Test8 10dic15', 'Test9 10dic15', 'Test10 10dic15'};
            app.ListBox.ItemsData = {'NScattoso_v1_01dic15.mat', 'NScattoso_v2_01dic15.mat', 'Scattoso_v1_01dic15.mat', 'Scattoso_v2_01dic15.mat', 'a1_10dic15.mat', 'a2_10dic15.mat', 'b1_10dic15.mat', 'b2_10dic15.mat', 'c1_10dic15.mat', 'c2_10dic15.mat'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TuttiiRisultatideiTestTab = uitab(app.TabGroup2);
            app.TuttiiRisultatideiTestTab.Title = 'Tutti i Risultati dei Test';
            app.TabelaTuttiTest = uitable(app.TuttiiRisultatideiTestTab);
            app.TabelaTuttiTest.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabelaTuttiTest.RowName = {};
            app.EsportainLaTeXButtonGroup = uibuttongroup(app.TuttiiRisultatideiTestTab);
            app.EsportainLaTeXButtonGroup.Title = 'Esporta in LaTeX';
            app.SiButton = uiradiobutton(app.EsportainLaTeXButtonGroup);
            app.SiButton.Text = 'Si';
            app.NoButton = uiradiobutton(app.EsportainLaTeXButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.CaricaTabella = uibutton(app.TuttiiRisultatideiTestTab, 'push');
            app.CaricaTabella.ButtonPushedFcn = createCallbackFcn(app, @CaricaTabellaButtonPushed, true);
            app.CaricaTabella.Text = 'Carica Risultati';
            app.DatitestconsimulazioneTab = uitab(app.TabGroup);
            app.DatitestconsimulazioneTab.Title = 'Dati test (con simulazione)';
            app.TabGroup4 = uitabgroup(app.DatitestconsimulazioneTab);
            app.GraficiTab_2 = uitab(app.TabGroup4);
            app.GraficiTab_2.Title = 'Grafici';
            app.TabGroup5 = uitabgroup(app.GraficiTab_2);
            app.Grafico1Tab_2 = uitab(app.TabGroup5);
            app.Grafico1Tab_2.Title = 'Grafico 1';
            app.Grafico_2_1 = uiaxes(app.Grafico1Tab_2);
            xlabel(app.Grafico_2_1, 'Asse X')
            ylabel(app.Grafico_2_1, 'Asse Y')
            app.Grafico2Tab_2 = uitab(app.TabGroup5);
            app.Grafico2Tab_2.Title = 'Grafico 2';
            app.Grafico_2_2 = uiaxes(app.Grafico2Tab_2);
            xlabel(app.Grafico_2_2, 'Asse X')
            ylabel(app.Grafico_2_2, 'Asse Y')
            app.Grafico3Tab_2 = uitab(app.TabGroup5);
            app.Grafico3Tab_2.Title = 'Grafico 3';
            app.Grafico_2_3 = uiaxes(app.Grafico3Tab_2);
            xlabel(app.Grafico_2_3, 'Asse X')
            ylabel(app.Grafico_2_3, 'Asse Y')
            app.Grafico4Tab_2 = uitab(app.TabGroup5);
            app.Grafico4Tab_2.Title = 'Grafico 4';
            app.Grafico_2_4 = uiaxes(app.Grafico4Tab_2);
            xlabel(app.Grafico_2_4, 'Asse X')
            ylabel(app.Grafico_2_4, 'Asse Y')
            app.Grafico5Tab_2 = uitab(app.TabGroup5);
            app.Grafico5Tab_2.Title = 'Grafico 5';
            app.Grafico_2_5 = uiaxes(app.Grafico5Tab_2);
            xlabel(app.Grafico_2_5, 'Asse X')
            ylabel(app.Grafico_2_5, 'Asse Y')
            app.Grafico3DTab_2 = uitab(app.TabGroup5);
            app.Grafico3DTab_2.Title = 'Grafico 3D';
            app.Grafico_2_6 = uiaxes(app.Grafico3DTab_2);
            xlabel(app.Grafico_2_6, 'Asse X')
            ylabel(app.Grafico_2_6, 'Asse Y')
            app.FunzioniExtraDropDownLabel = uilabel(app.GraficiTab_2);
            app.FunzioniExtraDropDownLabel.Text = 'Funzioni Extra';
            app.FunzioniExtra_2 = uidropdown(app.GraficiTab_2);
            app.FunzioniExtra_2.Items = {'Nessuna', 'Mostra Figure'};
            app.FunzioniExtra_2.ValueChangedFcn = createCallbackFcn(app, @FunzioniExtraValueChanged_2, true);
            app.FunzioniExtra_2.Enable = 'off';
            app.FunzioniExtra_2.Value = 'Nessuna';
            app.TabellaRisultati_2 = uitable(app.GraficiTab_2);
            app.TabellaRisultati_2.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabellaRisultati_2.RowName = {};
            app.StampaPDFButton_2 = uibutton(app.GraficiTab_2, 'push');
            app.StampaPDFButton_2.ButtonPushedFcn = createCallbackFcn(app, @StampaPDFButtonPushed_2, true);
            app.StampaPDFButton_2.Enable = 'off';
            app.StampaPDFButton_2.Text = 'Stampa PDF';
            app.SelezionaDatidaVisualizareListBoxLabel = uilabel(app.GraficiTab_2);
            app.SelezionaDatidaVisualizareListBoxLabel.Text = 'Seleziona Dati da Visualizare';
            app.ListBox_2 = uilistbox(app.GraficiTab_2);
            app.ListBox_2.Items = {'Funzione Scattosa', 'Funzione Sinusoidale', 'Funzione con Rumore'};
            app.ListBox_2.ItemsData = {'Funzione Scattosa', 'Funzione Sinusoidale', 'Funzione con rumore'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged_2, true);
            app.ListBox_2.Value = {};
            app.TuttiiRisultatideiTestTab_2 = uitab(app.TabGroup4);
            app.TuttiiRisultatideiTestTab_2.Title = 'Tutti i Risultati dei Test';
            app.TabellaTuttiTest_2 = uitable(app.TuttiiRisultatideiTestTab_2);
            app.TabellaTuttiTest_2.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabellaTuttiTest_2.RowName = {};
            app.CaricaTabella_2 = uibutton(app.TuttiiRisultatideiTestTab_2, 'push');
            app.CaricaTabella_2.ButtonPushedFcn = createCallbackFcn(app, @CaricaTabellaButtonPushed_2, true);
            app.CaricaTabella_2.Text = 'Carica Risultati';
            app.EsportainLaTeXButtonGroup_2 = uibuttongroup(app.TuttiiRisultatideiTestTab_2);
            app.EsportainLaTeXButtonGroup_2.Title = 'Esporta in LaTeX';
            app.SiButton_2 = uiradiobutton(app.EsportainLaTeXButtonGroup_2);
            app.SiButton_2.Text = 'Si';
            app.NoButton_2 = uiradiobutton(app.EsportainLaTeXButtonGroup_2);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.DatiacquisiticontelefonoTab = uitab(app.TabGroup);
            app.DatiacquisiticontelefonoTab.Title = 'Dati acquisiti con telefono';
            app.TabGroup6 = uitabgroup(app.DatiacquisiticontelefonoTab);
            app.GraficiTab_3 = uitab(app.TabGroup6);
            app.GraficiTab_3.Title = 'Grafici';
            app.TabGroup7 = uitabgroup(app.GraficiTab_3);
            app.Grafico1Tab_3 = uitab(app.TabGroup7);
            app.Grafico1Tab_3.Title = 'Grafico 1';
            app.Grafico_3_1 = uiaxes(app.Grafico1Tab_3);
            xlabel(app.Grafico_3_1, 'Asse X')
            ylabel(app.Grafico_3_1, 'Asse Y')
            app.Grafico2Tab_3 = uitab(app.TabGroup7);
            app.Grafico2Tab_3.Title = 'Grafico 2';
            app.Grafico_3_2 = uiaxes(app.Grafico2Tab_3);
            xlabel(app.Grafico_3_2, 'Asse X')
            ylabel(app.Grafico_3_2, 'Asse Y')
            app.Grafico3Tab_3 = uitab(app.TabGroup7);
            app.Grafico3Tab_3.Title = 'Grafico 3';
            app.Grafico_3_3 = uiaxes(app.Grafico3Tab_3);
            xlabel(app.Grafico_3_3, 'Asse X')
            ylabel(app.Grafico_3_3, 'Asse Y')
            app.Grafico4Tab_3 = uitab(app.TabGroup7);
            app.Grafico4Tab_3.Title = 'Grafico 4';
            app.Grafico_3_4 = uiaxes(app.Grafico4Tab_3);
            xlabel(app.Grafico_3_4, 'Asse X')
            ylabel(app.Grafico_3_4, 'Asse Y')
            app.Grafico5Tab_3 = uitab(app.TabGroup7);
            app.Grafico5Tab_3.Title = 'Grafico 5';
            app.Grafico_3_5 = uiaxes(app.Grafico5Tab_3);
            xlabel(app.Grafico_3_5, 'Asse X')
            ylabel(app.Grafico_3_5, 'Asse Y')
            app.Grafico3DTab_3 = uitab(app.TabGroup7);
            app.Grafico3DTab_3.Title = 'Grafico3D';
            app.Grafico_3_6 = uiaxes(app.Grafico3DTab_3);
            xlabel(app.Grafico_3_6, 'Asse X')
            ylabel(app.Grafico_3_6, 'Asse Y')
            app.SelezionaDatidaVisualizareListBoxLabel_2 = uilabel(app.GraficiTab_3);
            app.SelezionaDatidaVisualizareListBoxLabel_2.Text = 'Seleziona Dati da Visualizare';
            app.ListBox_3 = uilistbox(app.GraficiTab_3);
            app.ListBox_3.Items = {'Camminata', 'Corsa', 'Salti'};
            app.ListBox_3.ItemsData = {'Cammino.mat', 'Corsa.mat', 'Salti.mat', ''};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged_3, true);
            app.ListBox_3.Value = {};
            app.TabellaRisultati_3 = uitable(app.GraficiTab_3);
            app.TabellaRisultati_3.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabellaRisultati_3.RowName = {};
            app.FunzioniExtraDropDownLabel_2 = uilabel(app.GraficiTab_3);
            app.FunzioniExtraDropDownLabel_2.Text = 'Funzioni Extra';
            app.FunzioniExtra_3 = uidropdown(app.GraficiTab_3);
            app.FunzioniExtra_3.Items = {'Nessuna', 'Mostra Figure'};
            app.FunzioniExtra_3.ValueChangedFcn = createCallbackFcn(app, @FunzioniExtraValueChanged_3, true);
            app.FunzioniExtra_3.Enable = 'off';
            app.FunzioniExtra_3.Value = 'Nessuna';
            app.StampaPDFButton_3 = uibutton(app.GraficiTab_3, 'push');
            app.StampaPDFButton_3.ButtonPushedFcn = createCallbackFcn(app, @StampaPDFButtonPushed_3, true);
            app.StampaPDFButton_3.Enable = 'off';
            app.StampaPDFButton_3.Text = 'Stampa PDF';
            app.TuttiiRisultatideiTestTab_3 = uitab(app.TabGroup6);
            app.TuttiiRisultatideiTestTab_3.Title = 'Tutti i Risultati dei Test';
            app.TabellaTuttiTest_3 = uitable(app.TuttiiRisultatideiTestTab_3);
            app.TabellaTuttiTest_3.ColumnName = {'N'; 'D'; 'S'; 'L'; 'X'; 'LurchIndex'; 'AnalyticLurchIndex'};
            app.TabellaTuttiTest_3.RowName = {};
            app.CaricaTabella_3 = uibutton(app.TuttiiRisultatideiTestTab_3, 'push');
            app.CaricaTabella_3.ButtonPushedFcn = createCallbackFcn(app, @CaricaTabellaButtonPushed_3, true);
            app.CaricaTabella_3.Text = 'Carica Risultati';
            app.EsportainLaTeXButtonGroup_3 = uibuttongroup(app.TuttiiRisultatideiTestTab_3);
            app.EsportainLaTeXButtonGroup_3.Title = 'Esporta in LaTeX';
            app.SiButton_3 = uiradiobutton(app.EsportainLaTeXButtonGroup_3);
            app.SiButton_3.Text = 'Si';
            app.NoButton_3 = uiradiobutton(app.EsportainLaTeXButtonGroup_3);
            app.NoButton_3.Text = 'No';
            app.NoButton_3.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = progetto_matlab_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgettoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgettoUIFigure)
        end
    end
end